classdef ElFEO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FormaDropDown            matlab.ui.control.DropDown
        FormaDropDownLabel       matlab.ui.control.Label
        AlturacmEditField        matlab.ui.control.NumericEditField
        AlturacmEditFieldLabel   matlab.ui.control.Label
        CapacitanciaV            matlab.ui.control.NumericEditField
        CapacitanciapFKnob       matlab.ui.control.Knob
        CapacitanciapFKnobLabel  matlab.ui.control.Label
        StartButton              matlab.ui.control.Button
        Volumen                  matlab.ui.control.NumericEditField
        Volumencm3Label          matlab.ui.control.Label
        Liquidos                 matlab.ui.control.DropDown
        LquidoLabel              matlab.ui.control.Label
        Graph                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
         % Description
            epsilon = 8.85e-12;

            d = 0.02;
            
            % medidas bote
            L = 0.10;
            r = 0.04;
            % medidas cilindrico
            r2=0.02;
            
%             app.p = 6;
            % medidas placas
            LP = 0.10;
            bP = 0.05;
            
            h = 0;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            [X,Y,Z] = cylinder(0.04,50);
            h= 0.1;
            Z = Z*h;
               
            s = surf(app.Graph,X,Y,Z,'FaceAlpha',".2"','LineStyle','none');
            daspect(app.Graph,[1,1,1])
            
            Area = app.LP*app.bP;
            Cmin = (1*app.epsilon)*(Area/app.d);
            Cmin1 = Cmin * 10^12;
            Cmax = (80.4*app.epsilon)*(Area/app.d);
            Cmax1 = Cmax*10^12;
            app.CapacitanciapFKnob.Limits = [Cmin1,Cmax1];
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            cla(app.Graph)
            if app.FormaDropDown.Value == "Placas"
            app.h = -(((app.CapacitanciapFKnob.Value*10^-12)*app.d)/(app.bP*(1-app.Liquidos.Value)*app.epsilon)) + (app.LP/(1-app.Liquidos.Value));
            Altura = (app.h)*100; % introducimos el valor en mts, lo volvemos cm
            app.AlturacmEditField.Value = Altura;% se asigna el valor de la altura al campo de altura
            Volumen = (pi*app.r^2*app.h)*1000000;
            app.Volumen.Value = Volumen; % se asigna el volumen
            hold(app.Graph,"on")
            [X,Y,Z] = cylinder(0.04,50);
            h= 0.1;
            Z = Z*h;
            s = surf(app.Graph,X,Y,Z,'FaceAlpha',".2"','LineStyle','none');
            daspect(app.Graph,[1,1,1])
            
            
            t = linspace(-.025,.025,2);
            u = linspace(0,.1,2);
            [t,u] = meshgrid(t,u);
            q = zeros(size(u,1))+0.01;
            surf(app.Graph,q,t,u,'FaceColor',[0.8,0.5,0.2])
            daspect(app.Graph,[1,1,1])
            
            
            j = linspace(-.025,.025,2);
            k = linspace(0,.1,2);
            [j,k] = meshgrid(j,k);
            i = zeros(size(j,1))- 0.01;
            surf(app.Graph,i,j,k,'FaceColor',[0.8,0.5,0.2])
            daspect(app.Graph,[1,1,1])
            
            elseif app.FormaDropDown.Value == "Cilindro"
            app.h = -(((app.CapacitanciapFKnob.Value*10^-12)*(log(app.r/app.r2)))/(2*pi*(1-app.Liquidos.Value)*app.epsilon)) + (app.LP/(1-app.Liquidos.Value));
            Altura = (app.h)*100; % introducimos el valor en mts, lo volvemos cm
            app.AlturacmEditField.Value = Altura;% se asigna el valor de la altura al campo de altura
            Volumen = (pi*app.r^2*app.h-pi*app.r2^2*app.h)*1000000;
            app.Volumen.Value = Volumen; % se asigna el volumen
            hold(app.Graph,"on")
            [X,Y,Z] = cylinder(0.04,50);
            h= 0.1;%cilindro externo
            Z = Z*h;
            s = surf(app.Graph,X,Y,Z,'FaceAlpha',".2"','LineStyle','none');
            daspect(app.Graph,[1,1,1])
            
            [X,Y,Z] = cylinder(app.r2,50);
            h= 0.1;%cilindro interno
            Z = Z*h;
            s = surf(app.Graph,X,Y,Z,'FaceColor',[0.8,0.5,0.2]);
            daspect(app.Graph,[1,1,1])

            end
            l = app.Liquidos.Value;
            switch (l)
                case 80.4
                    [A,B,C] = cylinder(0.04,50);
                    alt = app.h;
                    C = C*alt;
                    liq = surf(app.Graph,A,B,C,'FaceColor',[0.18,0.85,0.92]);
                    liq.EdgeColor = 'none';
                    fill3(app.Graph,A(1,:),B(1,:),C(1,:),1, 'FaceColor',[0.18,0.85,0.92])
                    fill3(app.Graph,A(2,:),B(2,:),C(2,:),1, 'FaceColor',[0.18,0.85,0.92])
                    daspect(app.Graph,[1,1,1])
                    hold(app.Graph,"off")
                case 80
                   [A,B,C] = cylinder(0.04,50);
                    alt = app.h;
                    C = C*alt;
                    liq = surf(app.Graph,A,B,C,'FaceColor',[0.6,0.85,0.81]);
                    liq.EdgeColor = 'none';
                    fill3(app.Graph,A(1,:),B(1,:),C(1,:),1, 'FaceColor',[0.6,0.85,0.81])
                    fill3(app.Graph,A(2,:),B(2,:),C(2,:),1, 'FaceColor',[0.6,0.85,0.81])
                    daspect(app.Graph,[1,1,1])
                    hold(app.Graph,"off")
                case 42.5 %glicerina
                    [A,B,C] = cylinder(0.04,50);
                    alt = app.h;
                    C = C*alt;
                    liq = surf(app.Graph,A,B,C,'FaceColor','#efbac9');
                    liq.EdgeColor = 'none';
                    fill3(app.Graph,A(1,:),B(1,:),C(1,:),0, 'FaceColor','#efbac9')
                    fill3(app.Graph,A(2,:),B(2,:),C(2,:),0,'FaceColor','#efbac9')
                    daspect(app.Graph,[1,1,1])
                    hold(app.Graph,"off")
                    
                    case 2.35 %gasolina
                    [A,B,C] = cylinder(0.04,50);
                    alt = app.h;
                    C = C*alt;
                    liq = surf(app.Graph,A,B,C,'FaceColor','#d7584c');
                    liq.EdgeColor = 'none';
                    fill3(app.Graph,A(1,:),B(1,:),C(1,:),0, 'FaceColor','#d7584c')
                    fill3(app.Graph,A(2,:),B(2,:),C(2,:),0,'FaceColor','#d7584c')
                    daspect(app.Graph,[1,1,1])
                    hold(app.Graph,"off")
                    
                    case 35 %alcohol metílico
                    [A,B,C] = cylinder(0.04,50);
                    alt = app.h;
                    C = C*alt;
                    liq = surf(app.Graph,A,B,C,'FaceColor','#cb2dee');
                    liq.EdgeColor = 'none';
                    fill3(app.Graph,A(1,:),B(1,:),C(1,:),0, 'FaceColor','#cb2dee')
                    fill3(app.Graph,A(2,:),B(2,:),C(2,:),0,'FaceColor','#cb2dee')
                    daspect(app.Graph,[1,1,1])
                    hold(app.Graph,"off")
                    
                    case 32.6 %metanol
                    [A,B,C] = cylinder(0.04,50);
                    alt = app.h;
                    C = C*alt;
                    liq = surf(app.Graph,A,B,C,'FaceColor','#effbfa');
                    liq.EdgeColor = 'none';
                    fill3(app.Graph,A(1,:),B(1,:),C(1,:),0, 'FaceColor','#effbfa')
                    fill3(app.Graph,A(2,:),B(2,:),C(2,:),0,'FaceColor','#effbfa')
                    daspect(app.Graph,[1,1,1])
                    hold(app.Graph,"off")
                    
                    case 2.4 %aceite
                    [A,B,C] = cylinder(0.04,50);
                    alt = app.h;
                    C = C*alt;
                    liq = surf(app.Graph,A,B,C,'FaceColor','#f9d500');
                    liq.EdgeColor = 'none';
                    fill3(app.Graph,A(1,:),B(1,:),C(1,:),0, 'FaceColor','#f9d500')
                    fill3(app.Graph,A(2,:),B(2,:),C(2,:),0,'FaceColor','#f9d500')
                    daspect(app.Graph,[1,1,1])
                    hold(app.Graph,"off")
                    
                    case 24.3 %etanol
                    [A,B,C] = cylinder(0.04,50);
                    alt = app.h;
                    C = C*alt;
                    liq = surf(app.Graph,A,B,C,'FaceColor','#e9ab0b');
                    liq.EdgeColor = 'none';
                    fill3(app.Graph,A(1,:),B(1,:),C(1,:),0, 'FaceColor','#e9ab0b')
                    fill3(app.Graph,A(2,:),B(2,:),C(2,:),0, 'FaceColor','#e9ab0b')
                    daspect(app.Graph,[1,1,1])
                    hold(app.Graph,"off")
                            
                    case 110 %formamida
                    [A,B,C] = cylinder(0.04,50);
                    alt = app.h;
                    C = C*alt;
                    liq = surf(app.Graph,A,B,C,'FaceColor','#b54206');
                    liq.EdgeColor = 'none';
                    fill3(app.Graph,A(1,:),B(1,:),C(1,:),0, 'FaceColor','#b54206')
                    fill3(app.Graph,A(2,:),B(2,:),C(2,:),0,'FaceColor','#b54206')
                    daspect(app.Graph,[1,1,1])
                    hold(app.Graph,"off")
                    
                    case 20.7 %Acetona
                    [A,B,C] = cylinder(0.04,50);
                    alt = app.h;
                    C = C*alt;
                    liq = surf(app.Graph,A,B,C,'FaceColor','#ebcbf9');
                    liq.EdgeColor = 'none';
                    fill3(app.Graph,A(1,:),B(1,:),C(1,:),1, 'FaceColor','#ebcbf9')
                    fill3(app.Graph,A(2,:),B(2,:),C(2,:),1, 'FaceColor','#ebcbf9')
                    daspect(app.Graph,[1,1,1])
                    hold(app.Graph,"off")
                    
                    case 16.9 %Amoniaco
                    [A,B,C] = cylinder(0.04,50);
                    alt = app.h;
                    C = C*alt;
                    liq = surf(app.Graph,A,B,C,'FaceColor','#fe0400');
                    liq.EdgeColor = 'none';
                    fill3(app.Graph,A(1,:),B(1,:),C(1,:),1, 'FaceColor','#fe0400')
                    fill3(app.Graph,A(2,:),B(2,:),C(2,:),1, 'FaceColor','#fe0400')
                    daspect(app.Graph,[1,1,1])
                    hold(app.Graph,"off")

                    case 2 %Diesel
                    [A,B,C] = cylinder(0.04,50);
                    alt = app.h;
                    C = C*alt;
                    liq = surf(app.Graph,A,B,C,'FaceColor','#67aa4c');
                    liq.EdgeColor = 'none';
                    fill3(app.Graph,A(1,:),B(1,:),C(1,:),1, 'FaceColor','#67aa4c')
                    fill3(app.Graph,A(2,:),B(2,:),C(2,:),1, 'FaceColor','#67aa4c')
                    daspect(app.Graph,[1,1,1])
                    hold(app.Graph,"off")

                    case 2.284 %Benceno
                    [A,B,C] = cylinder(0.04,50);
                    alt = app.h;
                    C = C*alt;
                    liq = surf(app.Graph,A,B,C,'FaceColor','#D3D3D3');
                    liq.EdgeColor = 'none';
                    fill3(app.Graph,A(1,:),B(1,:),C(1,:),1, 'FaceColor','#D3D3D3')
                    fill3(app.Graph,A(2,:),B(2,:),C(2,:),1, 'FaceColor','#D3D3D3')
                    daspect(app.Graph,[1,1,1])
                    hold(app.Graph,"off")


            end
          
        end

        % Value changed function: Liquidos
        function LiquidosValueChanged(app, event)
            
            value = app.Liquidos.Value;
            if app.FormaDropDown.Value == "Placas"
            Area = app.LP*app.bP;
            Cmin = (1*app.epsilon)*(Area/app.d);
            Cmin1 = Cmin * 10^12;
            Cmax = (value*app.epsilon)*(Area/app.d);
            Cmax1 = Cmax * 10^12;
            elseif app.FormaDropDown.Value == "Cilindro"
            Area=app.LP*pi*app.r2*app.r2;
            Cmin = (2*pi*app.epsilon)*(app.LP/log(app.r/app.r2));
            Cmin1 = Cmin * 10^12;
            Cmax = (value*2*pi*app.epsilon)*(app.LP/log(app.r/app.r2));
            Cmax1 = Cmax * 10^12;
            end

            app.CapacitanciapFKnob.Limits = [Cmin1,Cmax1];
        end

        % Value changed function: CapacitanciapFKnob
        function CapacitanciapFKnobValueChanged(app, event)
            value = app.CapacitanciapFKnob.Value;
            app.CapacitanciaV.Value = app.CapacitanciapFKnob.Value;
        end

        % Value changed function: Volumen
        function VolumenValueChanged(app, event)
            value = app.Volumen.Value;
            
        end

        % Value changed function: AlturacmEditField
        function AlturacmEditFieldValueChanged(app, event)
            value = app.AlturacmEditField.Value;
            
        end

        % Value changed function: CapacitanciaV
        function CapacitanciaVValueChanged(app, event)
            value = app.CapacitanciaV.Value;
            
        end

        % Drop down opening function: FormaDropDown
        function FormaDropDownOpening(app, event)
            
        end

        % Value changed function: FormaDropDown
        function FormaDropDownValueChanged(app, event)
            value = app.FormaDropDown.Value;
            LiquidosValueChanged(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Graph = uiaxes(app.UIFigure);
            xlabel(app.Graph, 'X')
            ylabel(app.Graph, 'Y')
            zlabel(app.Graph, 'Z')
            app.Graph.SubtitleFontWeight = 'bold';
            app.LquidoLabel = uilabel(app.UIFigure);
            app.LquidoLabel.Text = 'Líquido';
            app.Liquidos = uidropdown(app.UIFigure);
            app.Liquidos.Items = {'Agua', 'Agua destilada', 'Glicerina', 'Gasolina', 'Alcohol Metílico', 'Metanol', 'Aceite', 'Etanol', 'Formamida', 'Acetona', 'Amoniaco', 'Diesel', 'Benceno'};
            app.Liquidos.ItemsData = [80.4 80 42.5 2.35 35 32.6 2.4 24.3 110 20.7 16.9 2 2.284];
            app.Liquidos.ValueChangedFcn = createCallbackFcn(app, @LiquidosValueChanged, true);
            app.Liquidos.Value = 80.4;
            app.Volumencm3Label = uilabel(app.UIFigure);
            app.Volumencm3Label.Text = 'Volumen (cm^3)';
            app.Volumen = uieditfield(app.UIFigure, 'numeric');
            app.Volumen.ValueDisplayFormat = '%.2f';
            app.Volumen.ValueChangedFcn = createCallbackFcn(app, @VolumenValueChanged, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.CapacitanciapFKnobLabel = uilabel(app.UIFigure);
            app.CapacitanciapFKnobLabel.Text = 'Capacitancia pF';
            app.CapacitanciapFKnob = uiknob(app.UIFigure, 'continuous');
            app.CapacitanciapFKnob.Limits = [0 10];
            app.CapacitanciapFKnob.ValueChangedFcn = createCallbackFcn(app, @CapacitanciapFKnobValueChanged, true);
            app.CapacitanciaV = uieditfield(app.UIFigure, 'numeric');
            app.CapacitanciaV.ValueChangedFcn = createCallbackFcn(app, @CapacitanciaVValueChanged, true);
            app.AlturacmEditFieldLabel = uilabel(app.UIFigure);
            app.AlturacmEditFieldLabel.Text = 'Altura (cm)';
            app.AlturacmEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlturacmEditField.ValueDisplayFormat = '%.2f';
            app.AlturacmEditField.ValueChangedFcn = createCallbackFcn(app, @AlturacmEditFieldValueChanged, true);
            app.FormaDropDownLabel = uilabel(app.UIFigure);
            app.FormaDropDownLabel.Text = 'Forma';
            app.FormaDropDown = uidropdown(app.UIFigure);
            app.FormaDropDown.Items = {'Placas', 'Cilindro'};
            app.FormaDropDown.DropDownOpeningFcn = createCallbackFcn(app, @FormaDropDownOpening, true);
            app.FormaDropDown.ValueChangedFcn = createCallbackFcn(app, @FormaDropDownValueChanged, true);
            app.FormaDropDown.Value = 'Placas';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ElFEO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end